classdef read_data_from_excel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AddButton             matlab.ui.control.Button
        AnemiaEditField       matlab.ui.control.EditField
        AnemiaEditFieldLabel  matlab.ui.control.Label
        HbEditField           matlab.ui.control.NumericEditField
        HbEditFieldLabel      matlab.ui.control.Label
        BlueEditField         matlab.ui.control.NumericEditField
        BlueEditFieldLabel    matlab.ui.control.Label
        GreenEditField        matlab.ui.control.NumericEditField
        GreenEditFieldLabel   matlab.ui.control.Label
        RedEditField          matlab.ui.control.NumericEditField
        RedEditFieldLabel     matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        ReadDataButton        matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        t % Table to share between callbacks
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            app.t = readtable("random.xlsx", "Sheet",2); %calling the second sheet
            app.UITable.Data = app.t; 
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            name = app.NameEditField.Value;
            red = app.RedEditField.Value;
            green = app.GreenEditField.Value;
            blue = app.BlueEditField.Value;
            hb = app.HbEditField.Value;
            anemia = app.AnemiaEditField.Value;
            new_row_entry = {name red green blue hb anemia};
            app.UITable.Data = [app.t; new_row_entry]
            app.t = app.UITable.Data
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Red'; 'Green'; 'Blue'; 'Hb'; 'Anemia'};
            app.UITable.RowName = {};
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.RedEditFieldLabel = uilabel(app.UIFigure);
            app.RedEditFieldLabel.Text = 'Red';
            app.RedEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenEditFieldLabel = uilabel(app.UIFigure);
            app.GreenEditFieldLabel.Text = 'Green';
            app.GreenEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlueEditFieldLabel = uilabel(app.UIFigure);
            app.BlueEditFieldLabel.Text = 'Blue';
            app.BlueEditField = uieditfield(app.UIFigure, 'numeric');
            app.HbEditFieldLabel = uilabel(app.UIFigure);
            app.HbEditFieldLabel.Text = 'Hb';
            app.HbEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnemiaEditFieldLabel = uilabel(app.UIFigure);
            app.AnemiaEditFieldLabel.Text = 'Anemia';
            app.AnemiaEditField = uieditfield(app.UIFigure, 'text');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = read_data_from_excel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end